classdef uiux < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        SetupPanel                     matlab.ui.container.Panel
        QualityLabel                   matlab.ui.control.Label
        QualityEditField               matlab.ui.control.NumericEditField
        UploadimageButton              matlab.ui.control.Button
        CompressimageButton            matlab.ui.control.Button
        compressionratioLabel          matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        JPEGCompressionAlgorithmLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: UploadimageButton
        function UploadimageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: CompressimageButton
        function CompressimageButtonPushed(app, event)
            global a;
                        % Check if an image has been uploaded
            if isempty(a)
                errordlg('Please upload an image first.', 'Error');
                return;
            end
            
            % Get the quality value from the qualityEditField
            quality = app.QualityEditField.Value;
            
            % Call your existing script 'code.m' to compress the image
            [compressedImage, compressionratio] = jpegcomp(a, quality); % Call your 'code.m' script with the original image and quality
            
            % Display the compressed image in compressedImageAxis
            imshow(compressedImage, 'Parent', app.UIAxes2);
            app.Label.Text = num2str(compressionratio);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'original image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'compressed image')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            zlabel(app.UIAxes2, '')
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Setup';
            app.QualityLabel = uilabel(app.SetupPanel);
            app.QualityLabel.Text = 'Quality =';
            app.QualityEditField = uieditfield(app.SetupPanel, 'numeric');
            app.QualityEditField.Limits = [0 99];
            app.QualityEditField.Value = 50;
            app.UploadimageButton = uibutton(app.SetupPanel, 'push');
            app.UploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageButtonPushed, true);
            app.UploadimageButton.Text = 'Upload image';
            app.CompressimageButton = uibutton(app.SetupPanel, 'push');
            app.CompressimageButton.ButtonPushedFcn = createCallbackFcn(app, @CompressimageButtonPushed, true);
            app.CompressimageButton.Text = 'Compress image';
            app.compressionratioLabel = uilabel(app.UIFigure);
            app.compressionratioLabel.Text = 'compression ratio =';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.JPEGCompressionAlgorithmLabel = uilabel(app.UIFigure);
            app.JPEGCompressionAlgorithmLabel.Text = 'JPEG Compression Algorithm ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = uiux

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end